classdef MetodoDeEliminacionDeGaussJordan < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalcularButton                matlab.ui.control.Button
        ResultadoLabel                matlab.ui.control.Label
        TableData                     matlab.ui.control.Table
        TableResultado                matlab.ui.control.Table
        TableResultadoMatriz          matlab.ui.control.Table
        FilasLabel                    matlab.ui.control.Label
        NumeroDeFilasDropDown         matlab.ui.control.DropDown
        ColumnasLabel                 matlab.ui.control.Label
        NumeroDeColumnasDropDown      matlab.ui.control.DropDown
        GenerarButton                 matlab.ui.control.Button
        TablafinalLabel               matlab.ui.control.Label
        ELIMINACINDEGAUSSJORDANLabel  matlab.ui.control.Label
        btnRetroceder                 matlab.ui.control.Button
        btnExternLink                 matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Value changed function: NumeroDeFilasDropDown
        function NumeroDeFilasDropDownValueChanged(app, event)
            
        end

        % Value changed function: NumeroDeColumnasDropDown
        function NumeroDeColumnasDropDownValueChanged(app, event)
            
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
            set(app.TableData, 'ColumnEditable', true)
            filas = str2double(app.NumeroDeFilasDropDown.Value);
            columnas = str2double(app.NumeroDeColumnasDropDown.Value);
            
            switch(columnas)
                case 3
                    set(app.TableData, 'ColumnName', {'X1','X2','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3'});
                case 4
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4'});
                case 5
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5'});
                case 6
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6'});
                case 7
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7'});
                case 8
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8'});
                case 9
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9'});
                case 10
                    set(app.TableData, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','Z'});
                    set(app.TableResultadoMatriz, 'ColumnName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','Z'});
                    set(app.TableResultado, 'RowName', {'X1','X2','X3','X4','X5','X6','X7','X8','X9','X10'});
            end
            set(app.TableResultadoMatriz, 'Data', {})
            set(app.TableResultado, 'Data', {})
            set(app.TableData, 'Data', cell(filas, columnas))
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            matrizData = get(app.TableData, 'Data');
            matrizData = str2double(matrizData);
            
            row = size(matrizData,1);
            col = size(matrizData,2);
            
            [row,col]=size(matrizData);
            for j=1:row-1
                for z=2:row
                    if matrizData(j,j)==0
                        t=matrizData(1,:);matrizData(1,:)=matrizData(z,:);
                        matrizData(z,:)=t;
                    end
                end
                for i=j+1:row
                    matrizData(i,:)=matrizData(i,:)-matrizData(j,:)*(matrizData(i,j)/matrizData(j,j));
                end
            end
            
            for j=row:-1:2
                for i=j-1:-1:1
                    matrizData(i,:)=matrizData(i,:)-matrizData(j,:)*(matrizData(i,j)/matrizData(j,j));
                end
            end
            
            for s=1:row
                matrizData(s,:)=matrizData(s,:)/matrizData(s,s);
                x(s)=matrizData(s,col);
            end
            disp('Gauss-Jordan method:');
            
            set(app.TableResultado, 'ColumnName','Valor');
            set(app.TableResultadoMatriz, 'Data', matrizData)
            set(app.TableResultado, 'Data', x');
        end

        % Button pushed function: btnRetroceder
        function btnRetrocederButtonPushed(app, event)
            FormularioPrincipal;
            delete(app);
        end

        % Button pushed function: btnExternLink
        function btnExternLinkButtonPushed(app, event)
            web('https://www.youtube.com/watch?v=dFmGzr1j6eY')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CalcularButton = uibutton(app.UIFigure, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.ResultadoLabel = uilabel(app.UIFigure);
            app.ResultadoLabel.Text = 'Resultado';
            app.TableData = uitable(app.UIFigure);
            app.TableData.ColumnName = '';
            app.TableData.RowName = {};
            app.TableResultado = uitable(app.UIFigure);
            app.TableResultado.ColumnName = '';
            app.TableResultado.RowName = {};
            app.TableResultadoMatriz = uitable(app.UIFigure);
            app.TableResultadoMatriz.ColumnName = '';
            app.TableResultadoMatriz.RowName = {};
            app.FilasLabel = uilabel(app.UIFigure);
            app.FilasLabel.Text = 'Filas:';
            app.NumeroDeFilasDropDown = uidropdown(app.UIFigure);
            app.NumeroDeFilasDropDown.Items = {'2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumeroDeFilasDropDown.ValueChangedFcn = createCallbackFcn(app, @NumeroDeFilasDropDownValueChanged, true);
            app.NumeroDeFilasDropDown.Value = '2';
            app.ColumnasLabel = uilabel(app.UIFigure);
            app.ColumnasLabel.Text = 'Columnas:';
            app.NumeroDeColumnasDropDown = uidropdown(app.UIFigure);
            app.NumeroDeColumnasDropDown.Items = {'3', '4', '5', '6', '7', '8', '9', '10'};
            app.NumeroDeColumnasDropDown.ValueChangedFcn = createCallbackFcn(app, @NumeroDeColumnasDropDownValueChanged, true);
            app.NumeroDeColumnasDropDown.Value = '3';
            app.GenerarButton = uibutton(app.UIFigure, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.TablafinalLabel = uilabel(app.UIFigure);
            app.TablafinalLabel.Text = 'Tabla final:';
            app.ELIMINACINDEGAUSSJORDANLabel = uilabel(app.UIFigure);
            app.ELIMINACINDEGAUSSJORDANLabel.Text = 'ELIMINACIÓN DE GAUSS JORDAN';
            app.btnRetroceder = uibutton(app.UIFigure, 'push');
            app.btnRetroceder.ButtonPushedFcn = createCallbackFcn(app, @btnRetrocederButtonPushed, true);
            app.btnRetroceder.Icon = 'back.png';
            app.btnRetroceder.Text = '';
            app.btnExternLink = uibutton(app.UIFigure, 'push');
            app.btnExternLink.ButtonPushedFcn = createCallbackFcn(app, @btnExternLinkButtonPushed, true);
            app.btnExternLink.Icon = 'extern-link.png';
            app.btnExternLink.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MetodoDeEliminacionDeGaussJordan

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end